classdef Pool_transmit_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DistanceEditField             matlab.ui.control.NumericEditField
        DistanceTxRxmEditFieldLabel   matlab.ui.control.Label
        LocationDropDown              matlab.ui.control.DropDown
        LocationLabel                 matlab.ui.control.Label
        GenerateSignalButton          matlab.ui.control.Button
        RX_ID_DevEditField            matlab.ui.control.NumericEditField
        RX_ID_DevEditFieldLabel       matlab.ui.control.Label
        TX_ID_DevEditField            matlab.ui.control.NumericEditField
        TX_ID_DevEditFieldLabel       matlab.ui.control.Label
        UseDualDAQSetupSwitch         matlab.ui.control.Switch
        DAQSETUPSwitchLabel           matlab.ui.control.Label
        ClearqueueButton              matlab.ui.control.Button
        ImagetransmissionPanel        matlab.ui.container.Panel
        ImageRxLabel                  matlab.ui.control.Label
        ImageTxLabel                  matlab.ui.control.Label
        ImagePathEditField            matlab.ui.control.EditField
        LoadimagejpgButton            matlab.ui.control.Button
        TransmitphotoButton           matlab.ui.control.Button
        ImageRX                       matlab.ui.control.Image
        ImageTX                       matlab.ui.control.Image
        ExtraRecordingTime            matlab.ui.control.NumericEditField
        ExtrarecordingtimesEditFieldLabel  matlab.ui.control.Label
        ChirpPathEditField            matlab.ui.control.EditField
        MOCZexperimentGUIMOCZsignalsmodeLabel  matlab.ui.control.Label
        TransmitChirpfortestCheckBox  matlab.ui.control.CheckBox
        AnalyzeRecordingPanel         matlab.ui.container.Panel
        TruncateEditField             matlab.ui.control.NumericEditField
        TruncatetoEditFieldLabel      matlab.ui.control.Label
        BEREditField                  matlab.ui.control.NumericEditField
        BEREditFieldLabel             matlab.ui.control.Label
        PowerEditField                matlab.ui.control.NumericEditField
        PowerEditFieldLabel           matlab.ui.control.Label
        LEditField                    matlab.ui.control.NumericEditField
        LEditFieldLabel               matlab.ui.control.Label
        KEditField                    matlab.ui.control.NumericEditField
        KEditFieldLabel               matlab.ui.control.Label
        AnalyzePathEditField          matlab.ui.control.EditField
        loadRecordingFileButton       matlab.ui.control.Button
        AnalyzeButton                 matlab.ui.control.Button
        PausetimeEditField            matlab.ui.control.NumericEditField
        PausetimesEditFieldLabel      matlab.ui.control.Label
        recordingsPathEditField       matlab.ui.control.EditField
        ChooserecordingspathButton    matlab.ui.control.Button
        SignalfilesqueueLabel         matlab.ui.control.Label
        ProgressTextArea              matlab.ui.control.TextArea
        ProgressTextAreaLabel         matlab.ui.control.Label
        LoadedFilesTextArea           matlab.ui.control.TextArea
        START_button                  matlab.ui.control.Button
        loadSignalFolder_button       matlab.ui.control.Button
        loadSignalFile_button         matlab.ui.control.Button
    end

    
    properties (Access = public)
        TransmitSignalsData; % full data on the tranmis signal
        image; % .jpg image file
        stopRequested = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadSignalFile_button
        function loadSignalFile_buttonButtonPushed(app, event)
            %Open a dialog to select only .mat files
            [fileName, filePath] = uigetfile('*.mat', 'Select a .MAT-file');
            figure(app.UIFigure);

            %Check if the user clicked "Cancel"
            if isequal(fileName, 0)
                return; % Exit the function without doing anything
            end
        
            % Display the file name in the text box next to the button
            % (Replace 'signal_file_name' with the actual name of your text component in the App Designer hierarchy)
            app.LoadedFilesTextArea.Value = fileName; 
        
            %Load the file and extract the data
            fullFilePath = fullfile(filePath, fileName);
            
            try
                loadedData = load(fullFilePath);
                safeFieldName = matlab.lang.makeValidName(erase(fileName, '.mat'));
                
                %Create a brand-new, empty structure
                freshStruct = struct();
                
                % Add your single loaded file to this fresh structure
                freshStruct.(safeFieldName) = loadedData;
                
                % OVERWRITE the app property (wiping old data)
                app.TransmitSignalsData = freshStruct;
                
                %OVERWRITE the text area so it only shows the new file
                app.LoadedFilesTextArea.Value = {fileName};
                
            catch 
                uialert(app.UIFigure, 'The selected file does not contain valid data.', 'Load Error');
            end
        end

        % Button pushed function: loadSignalFolder_button
        function loadSignalFolder_buttonButtonPushed(app, event)
            %Open a dialog to select a folder
            folderPath = uigetdir('', 'Select a Folder containing MAT-files');
            figure(app.UIFigure);
            
            % Check if the user clicked "Cancel"
            if isequal(folderPath, 0)
                return; 
            end
            
            %Get a list of all .mat files in that folder
            matFiles = dir(fullfile(folderPath, '*.mat'));

            % Initialize an empty cell array to hold the file names for display
            fileNamesForDisplay = {};
            
            % Check if the folder is empty of .mat files
            if isempty(matFiles)
                uialert(app.UIFigure, 'No .mat files were found in this folder.', 'Empty Folder');
                return;
            end
            
            %Initialize a temporary struct to hold everything
            masterDataStruct = struct();
            
            % Loop through every file, load it, and save it to the struct
            for i = 1:length(matFiles)
                fileName = matFiles(i).name;
                fullFilePath = fullfile(folderPath, fileName);
                
                try
                    % Load all variables from this specific file (P, signal_pb_total, simParams)
                    loadedData = load(fullFilePath);
                    
                    % Create a safe field name (e.g., "my_data.mat" becomes "my_data")
                    safeFieldName = matlab.lang.makeValidName(erase(fileName, '.mat'));
                    
                    % Save it as a nested structure inside the master struct
                    masterDataStruct.(safeFieldName) = loadedData;

                    %Add the original file name to our display list as a new row
                    fileNamesForDisplay{end+1, 1} = fileName;
                    
                catch
                    % If one file is corrupted, you can warn the user but keep loading the rest
                    warning('Could not load file: %s', fileName);
                end
            end
            
            % 5. Save the massive master structure to your app's property
            app.TransmitSignalsData = masterDataStruct;
            
            % Optional: Update a text box next to the button to show success
            % app.folder_status_text.Value = sprintf('Successfully loaded %d files.', length(matFiles));

            app.LoadedFilesTextArea.Value = fileNamesForDisplay;


        end

        % Button pushed function: START_button
        function START_buttonButtonPushed(app, event)
            % =========================================================
            % DISABLE BUTTONS DURING TRANSMISSION
            % =========================================================
            app.ClearqueueButton.Enable = 'off';
            app.START_button.Enable = 'off';      
            app.loadSignalFile_button.Enable = "off";
            app.loadSignalFolder_button.Enable = "off";
            Fs = 200e3;

            % =========================================================
            % Check what the user actually wants to do
            % =========================================================
            doChirp = app.TransmitChirpfortestCheckBox.Value;
            hasQueue = ~isempty(app.TransmitSignalsData);
            
            % If they didn't check the box AND the queue is empty, THEN throw the error
            if ~doChirp && ~hasQueue
                uialert(app.UIFigure, 'No data loaded and Chirp is unchecked. Nothing to transmit!', 'Error');
                return;
            end

            % =========================================================
            % Verify the save directory
            % =========================================================
            saveDirectory = app.recordingsPathEditField.Value;
            
            % Check if the string is empty OR if the folder somehow got deleted
            if isempty(saveDirectory) || ~isfolder(saveDirectory)
                uialert(app.UIFigure, 'Please select a valid Save Directory before transmitting.', 'Save Error');
                return;
            end
                        
            % =========================================================
            % INITIALIZE HARDWARE (ONCE before the loop)
            % =========================================================

            devList = daqlist;
        
            % Check the GUI switch for Dual DAQ mode
            if strcmp(app.UseDualDAQSetupSwitch.Value, 'Dual')
                % --- DUAL DAQ MODE ---
                app.ProgressTextArea.Value = 'Configuring Dual DAQs...';
                drawnow;
                
                % Assign Device IDs (Change indices if your TX is Dev2 and RX is Dev1)
                devTx = devList.DeviceID(app.TX_ID_DevEditField.Value); 
                devRx = devList.DeviceID(app.RX_ID_DevEditField.Value); 
                
                % Setup Transmitter
                d_tx = daq("ni");
                addoutput(d_tx, devTx, "ao0", "Voltage");
                d_tx.Rate = Fs; % 100 [kHz]
                
                % Setup Receiver
                d_rx = daq("ni");
                addinput(d_rx, devRx, "ai0", "Voltage");
                addinput(d_rx, devRx, "ai1", "Voltage");
                d_rx.Rate = Fs;  % 100[kHz]

                app.ProgressTextArea.Value = 'Transmitting and recording (Dual DAQ Mode)...';
                drawnow;
                
            else
                % --- SINGLE DAQ MODE (Original) ---
                app.ProgressTextArea.Value = 'Configuring Single DAQ...';
                drawnow;
                
                d = daq("ni");
                devID = devList.DeviceID(1);
                
                addinput(d, devID, "ai0", "Voltage");
                addinput(d, devID, "ai1", "Voltage");
                addoutput(d, devID, "ao0", "Voltage");
                d.Rate = Fs;

                
                app.ProgressTextArea.Value = 'Transmitting and recording (Single Mode)...';
                drawnow;
                
            end

            % OLD hardware setup - only one sampler support
            % try
            %     d = daq("ni");
            %     devList = daqlist;
            %     devID = devList.DeviceID(1);
            % 
            %     % Set up the physical channels (match these to your actual wiring!)
            %     addinput(d, devID, "ai0", "Voltage");
            %     addinput(d, devID, "ai1", "Voltage");
            %     addoutput(d, devID, "ao0", "Voltage");
            % catch
            %     uialert(app.UIFigure, 'Could not connect to NI Sampler. Check USB/Power.', 'Hardware Error');
            %     return; % Stop execution if hardware fails
            % end
            
            % Set up a text file to log the session
            logFileName = fullfile(saveDirectory, 'transmission_session.txt'); 
            fid = fopen(logFileName, 'a');
            fprintf(fid, '--- Starting new transmission batch at %s ---\n', char(datetime('now')));

            % =========================================================
            % TRANSMIT CHIRP (If requested)
            % =========================================================
            if doChirp
                chirpPath = app.ChirpPathEditField.Value;
                
                % Verify the path exists
                if isempty(chirpPath) || ~isfile(chirpPath)
                    uialert(app.UIFigure, 'Chirp file path is invalid.', 'Error');
                    fclose(fid);
                    return;
                end
                
                try
                    % Load ONLY the 'y_scaled' variable
                    loadedChirp = load(chirpPath, 'y_scaled');
                    chirpSignal = loadedChirp.y_scaled;
                    
                    % Update UI to show the chirp is running
                    app.ProgressTextArea.Value = 'Transmitting: CHIRP TEST...';
                    drawnow;
                    

                    % =====================================================
                    % ZERO-PADDING FOR CHIRP ISI CAPTURE
                    % =====================================================
                    listenTime = app.ExtraRecordingTime.Value; % Set how many seconds to record after transmitting
                    numZeros = round(listenTime * Fs); 
                    
                    % Force chirpSignal into a column vector and add zeros
                    paddedChirp = [chirpSignal(:); zeros(numZeros, 1)];

                    % if strcmp(app.UseDualDAQSetupSwitch.Value, 'Dual')
                    % 
                    % 
    %                     totalCaptureTime = (length(paddedChirp)/d_rx.Rate) + 1.0;
                    %     % Start the RX box listening in the background
                    %     start(d_rx, "Duration", seconds(totalCaptureTime));
                    % 
                    %     % Instantly fire the TX box (This blocks until finished)
                    %     write(d_tx, paddedChirp);
                    % 
                    %     % Fetch the recorded data buffer from the RX box
                    %     chirpRecordedData = read(d_rx, "all");
                    % 
                    % else
                        % Transmit the chirp - one sampler

                    if strcmp(app.UseDualDAQSetupSwitch.Value, 'Dual')
                        % Start the RX box listening in the background
                        totalCaptureTime = (length(paddedChirp)/d_rx.Rate) + 1.0;
                        start(d_rx, "Duration", seconds(totalCaptureTime));
                        
                        % Instantly fire the TX box (This blocks until finished)
                        write(d_tx, paddedChirp);
                        
                        % Fetch the recorded data buffer from the RX box
                        chirpRecordedData = read(d_rx, "all");
                                                % SAVE ONLY THE RECORDED VOLTAGES
                        chirpSavePath = fullfile(saveDirectory, 'chirp_recorded_result.mat');
                        save(chirpSavePath, 'chirpRecordedData');
                        
                        % Pause using your GUI's Pause time Edit Field!
                        % (Replace 'PausetimesEditField' with your actual component name)
                        pause(app.PausetimeEditField.Value);

                    else
                        preload(d, paddedChirp);
    
                        % Transmit and record the voltages
                        chirpRecordedData = readwrite(d, paddedChirp);
    
                        % if app.StopRequested
                        %         uialert(app.UIFigure, 'stopped by user, recording not saved.', 'warning');
                        % else
                        fprintf(fid, 'Transmitted CHIRP TEST successfully.\n');
    
                        app.ProgressTextArea.Value = "Finished";
                    % end

                        % SAVE ONLY THE RECORDED VOLTAGES
                        chirpSavePath = fullfile(saveDirectory, 'chirp_recorded_result.mat');
                        save(chirpSavePath, 'chirpRecordedData');
                        
                        % Pause using your GUI's Pause time Edit Field!
                        % (Replace 'PausetimesEditField' with your actual component name)
                        pause(app.PausetimeEditField.Value);
                    end
                    %Process recieved chirp
                    app.ProgressTextArea.Value = "Processing recieved chirp...";
                    drawnow;
                    chirpFnc(2, chirpSavePath);
                catch
                    uialert(app.UIFigure, 'Failed to load or transmit chirp. Ensure the file contains "y_scaled".', 'Chirp Error');
                    fclose(fid);
                    return;
                end
            end
            
            % =========================================================
            % BATCH EXECUTION LOOP
            % =========================================================
            if hasQueue
                queuedFiles = fieldnames(app.TransmitSignalsData);
                for i = 1:length(queuedFiles)
                    currentName = queuedFiles{i};
                    
                    % Extract the data for this specific iteration
                    currentSignal = app.TransmitSignalsData.(currentName).signal_pb_total;
                    currentParams = app.TransmitSignalsData.(currentName).simParams;
                    currentParams.location = app.LocationDropDown.Value;
                    currentParams.distance_tx_rx = app.DistanceEditField.Value;
                    
                    % Update UI to show progress
                    app.ProgressTextArea.Value = sprintf('Transmitting: %s...', currentName);
                    drawnow; 
                    
                    % Extract sampling rate from saved struct
                    if isfield(currentParams, 'Fs')
                        d.Rate = currentParams.Fs;
                    else
                        warning('No "Fs" found in simParams for %s. Using default 200e3.', currentName);
                        d.Rate = 200e3; 
                    end

                    % =====================================================
                    % ZERO-PADDING FOR ISI CAPTURE
                    % =====================================================
                    listenTime = app.ExtraRecordingTime.Value; % Set how many seconds to record after transmitting
                    
                    % Calculate exact number of zero samples (using round to be safe)
                    numZeros = round(listenTime * Fs); 
                    
                    % Force currentSignal into a column vector with (:), then add zeros
                    paddedSignal = [currentSignal(:); zeros(numZeros, 1)];

                    
                    if strcmp(app.UseDualDAQSetupSwitch.Value, 'Dual')
                        % Start the RX box listening in the background
                        totalCaptureTime = (length(paddedSignal)/d_rx.Rate) + 1.0;
                        start(d_rx, "Duration", seconds(totalCaptureTime));
                        
                        % Instantly fire the TX box (This blocks until finished)
                        write(d_tx, paddedSignal);
                        
                        % Fetch the recorded data buffer from the RX box
                        recordedData = read(d_rx, "all");

                    else
                        
                        % Preload the PADDED signal to the hardware buffer
                        preload(d, paddedSignal);
                        
                        % Transmit and Record simultaneously
                        % inData will be a timetable containing the recorded ai0 and ai1 voltages
                        recordedData = readwrite(d, paddedSignal);
                    end
                    
                    % ----------------------------------------------------
                    % Log the success
                    % ----------------------------------------------------
                    fprintf(fid, 'Transmitted %s successfully at fs=%d.\n', currentName, d.Rate);
                    % Grab the current list of files from the Text Area
                    currentList = app.LoadedFilesTextArea.Value;
                    
                    % Ensure it is a cell array so we can edit a specific row
                    if ischar(currentList)
                        currentList = {currentList};
                    end
                    
                    % Add a checkmark to the current file's line
                    currentList{i} = sprintf('✅ %s', currentList{i});
                    
                    % Push the updated list back to the GUI
                    app.LoadedFilesTextArea.Value = currentList;
                    drawnow; % Force the GUI to update immediately
    
                    
                    % ----------------------------------------------------
                    % SAVE THE RECORDED DATA AND PARAMETERS
                    % ----------------------------------------------------
                    % Create a new filename based on the original signal name
                    baseFileName = sprintf('%s_recorded_result.mat', currentName);
                    fullSavePath = fullfile(saveDirectory, baseFileName);
                    
                    % Save both the new recording AND the original struct
                    save(fullSavePath, 'recordedData', 'currentParams');
                    
                    % Pause for 2 seconds before the next transmission
                    if i < length(queuedFiles)
                        pause(app.PausetimeEditField.Value); %pause(2) default
                    end
                end
                
                fclose(fid);
                uialert(app.UIFigure, 'Batch transmission complete!', 'Success');
            end

            app.ClearqueueButton.Enable = 'on';
            app.START_button.Enable = 'on';      
            app.loadSignalFile_button.Enable = "on";
            app.loadSignalFolder_button.Enable = "on";

        end

        % Button pushed function: ChooserecordingspathButton
        function ChooserecordingspathButtonPushed(app, event)
            % Open a dialog to select a folder
            saveDir = uigetdir('', 'Select Directory to Save Results of current batch');
            
            % Force the app window back to the front
            figure(app.UIFigure);
            
            % Check if the user clicked "Cancel"
            if isequal(saveDir, 0)
                return; 
            end
            
            % Display the path in the UI
            % (Replace 'SavePathEditField' with the actual name of your component)
            app.recordingsPathEditField.Value = saveDir;
        end

        % Value changed function: TransmitChirpfortestCheckBox
        function TransmitChirpfortestCheckBoxValueChanged(app, event)
            value = app.TransmitChirpfortestCheckBox.Value;
            if value == true
                app.MOCZexperimentGUIMOCZsignalsmodeLabel.Text = "MOCZ experiment GUI - Chirp mode";
                app.MOCZexperimentGUIMOCZsignalsmodeLabel.BackgroundColor = '#FFB6C1';
                app.loadSignalFile_button.Enable = "off";
                app.loadSignalFolder_button.Enable = "off";
                app.ClearqueueButton.Enable = "off";

                app.TransmitSignalsData = []; 
                app.LoadedFilesTextArea.Value = '';
                
                %Open dialog to select the chirp file
                [fileName, filePath] = uigetfile('*.mat', 'Select Chirp .MAT file');
                
                % Force the app window back to the front
                figure(app.UIFigure); 
                
                % Check if the user clicked "Cancel"
                if isequal(fileName, 0)
                    % Uncheck the box since they canceled
                    app.TransmitChirpfortestCheckBox.Value = false;
                    return; 
                end
                
                %  Save the full path to the edit field below the checkbox
                % (Replace 'ChirpPathEditField' with your actual UI component name)
                app.ChirpPathEditField.Value = fullfile(filePath, fileName);
            else
                % If they uncheck the box, clear out the text field
                app.MOCZexperimentGUIMOCZsignalsmodeLabel.Text = "MOCZ experiment GUI - MOCZ signals mode";
                app.MOCZexperimentGUIMOCZsignalsmodeLabel.BackgroundColor = '#facf6b';
                app.loadSignalFile_button.Enable = "on";
                app.loadSignalFolder_button.Enable = "on";
                app.ClearqueueButton.Enable = "on";
                app.ChirpPathEditField.Value = '';
            end
        end

        % Button pushed function: ClearqueueButton
        function ClearqueueButtonPushed(app, event)
            %Wipe the master queue memory
            app.TransmitSignalsData = []; 
            
            % Clear the visual text box
            app.LoadedFilesTextArea.Value = ''; 

        end

        % Button pushed function: LoadimagejpgButton
        function LoadimagejpgButtonPushed(app, event)
            % Open a dialog to select only .jpg or .jpeg files
            [fileName, filePath] = uigetfile({'*.jpg;*.jpeg', 'JPEG Images (*.jpg, *.jpeg)'}, 'Select a JPG Image');
            
            % Force the app window back to the front
            figure(app.UIFigure);
            
            % Check if the user clicked "Cancel"
            if isequal(fileName, 0)
                return; % Exit the function without doing anything
            end
            
            % Create the full file path safely
            fullFilePath = fullfile(filePath, fileName);
            
            % Display the path in the edit field
            app.ImagePathEditField.Value = fullFilePath;
            
            try
                % Read the raw image
                img_raw = imread(fullFilePath);
                
                % =========================================================
                % PARSE IMAGE (MOCZ Dimensions & Binary)
                % =========================================================
                % Resize it to exactly 256x256 pixels
                img_resized = imresize(img_raw, [256, 256]);
                
                % Convert to grayscale if it is a color image (RGB)
                if size(img_resized, 3) == 3
                    img_gray = rgb2gray(img_resized);
                else
                    img_gray = img_resized;
                end
                
                % Convert to binary (pure black and white - logical 0 and 1)
                img_binary = imbinarize(img_gray);
                
                % Save the processed matrix to the app property for later transmission
                app.image = img_binary;
                
                % =========================================================
                % DISPLAY IN GUI
                % =========================================================
                % UI Image components cannot natively display 'logical' matrices.
                % We multiply by 255 and cast to uint8 to render it as standard black & white.
                displayImg = uint8(img_binary) * 255;

                % Replicate the 2D image into 3 channels (RGB) so the UI component accepts it
                displayImgRGB = cat(3, displayImg, displayImg, displayImg);
                
                % Send the image to the 'Image Tx' component
                app.ImageTX.ImageSource = displayImgRGB;
                
            catch ME
                % Pop up an error if the file is corrupted or unreadable
                uialert(app.UIFigure, ['Failed to load or process the image: ', ME.message], 'Load Error');
           end
        end

        % Button pushed function: TransmitphotoButton
        function TransmitphotoButtonPushed(app, event)
            Fs = 200e3;
            
            % Check if an image is actually loaded in memory
            if isempty(app.image)
                uialert(app.UIFigure, 'No image loaded! Please load a .jpg first.', 'Error');
                return;
            end
            
            % Verify Save Directory
            saveDirectory = app.recordingsPathEditField.Value;
            if isempty(saveDirectory) || ~isfolder(saveDirectory)
                uialert(app.UIFigure, 'Please select a valid Save Directory before transmitting.', 'Save Error');
                return;
            end
            
            % Lock Buttons during the entire transmission process
            app.LoadimagejpgButton.Enable = 'off';
            app.LoadimagejpgButton.Enable = 'off';
            drawnow; % Force UI to update immediately
            
            try
                % =========================================================
                % TX LOGIC (Generate Signal)
                % =========================================================
                % Get the transmission signal and the updated MOCZ params
                [txSignal, imageParams] = image_tx_rx('tx', app.image, []);
                imageParams.location = app.LocationDropDown.Value;
                imageParams.distance_tx_rx = app.DistanceEditField.Value;
                imageParams.n_trun = app.TruncateEditField.Value;
                
                % Update UI to show progress 
                app.ProgressTextArea.Value = 'Transmitting Photo...';
                drawnow;
                
                % =========================================================
                % HARDWARE TRANSMISSION
                % =========================================================

                devList = daqlist;
        
                % Check the GUI switch for Dual DAQ mode
                if strcmp(app.UseDualDAQSetupSwitch.Value, 'Dual')
                    % --- DUAL DAQ MODE ---
                    app.ProgressTextArea.Value = 'Configuring Dual DAQs...';
                    drawnow;
                    
                    % Assign Device IDs (Change indices if your TX is Dev2 and RX is Dev1)
                    devTx = devList.DeviceID(app.TX_ID_DevEditField.Value); 
                    devRx = devList.DeviceID(app.RX_ID_DevEditField.Value); 
                    
                    % Setup Transmitter
                    d_tx = daq("ni");
                    addoutput(d_tx, devTx, "ao0", "Voltage");
                    d_tx.Rate = Fs; % 100 [kHz]
                    
                    % Setup Receiver
                    d_rx = daq("ni");
                    addinput(d_rx, devRx, "ai0", "Voltage");
                    addinput(d_rx, devRx, "ai1", "Voltage");
                    d_rx.Rate = Fs;  % 100[kHz]
    
                    app.ProgressTextArea.Value = 'Transmitting and recording (Dual DAQ Mode)...';
                    drawnow;
                    
                else
                    % --- SINGLE DAQ MODE (Original) ---
                    app.ProgressTextArea.Value = 'Configuring Single DAQ...';
                    drawnow;
                    
                    d = daq("ni");
                    devID = devList.DeviceID(1);
                    
                    addinput(d, devID, "ai0", "Voltage");
                    addinput(d, devID, "ai1", "Voltage");
                    addoutput(d, devID, "ao0", "Voltage");
                    d.Rate = Fs;


                    % Set sample rate safely (Checking for both Fs and fs)
                    if isfield(imageParams, 'Fs')
                        d.Rate = imageParams.Fs;
                    elseif isfield(imageParams, 'fs')
                        d.Rate = imageParams.fs;
                    else
                        d.Rate = 200e3; % Default fallback
                    end
                    
                    app.ProgressTextArea.Value = 'Transmitting and recording (Single Mode)...';
                    drawnow;
                    
                end

                
                % Zero padding for ISI capture 
                listenTime = app.ExtraRecordingTime.Value;
                numZeros = round(listenTime * Fs);

                paddedSignal = [txSignal(:); zeros(numZeros, 1)];

                % ------ DEBUG -----------
                %numZerosStart = round(listenTime*d.Rate); % only for tests
                %paddedSignal = [zeros(numZerosStart,1); paddedSignal];
                %--------------------------------

                if strcmp(app.UseDualDAQSetupSwitch.Value, 'Dual')
                    totalCaptureTime = (length(paddedSignal)/d_rx.Rate) + 1.0;
                    % Start the RX box listening in the background
                    start(d_rx, "Duration", seconds(totalCaptureTime));
                    
                    % Instantly fire the TX box (This blocks until finished)
                    write(d_tx, paddedSignal);
                    
                    % Fetch the recorded data buffer from the RX box
                    recordedData = read(d_rx, "all");

                else
                    
                    % Transmit and Record simultaneously
                    % inData will be a timetable containing the recorded ai0 and ai1 voltages
                    recordedData = readwrite(d, paddedSignal);
                end


                % =========================================================
                % SAVE RAW RECORDING FIRST 
                % =========================================================
                % Create the timestamp and file name immediately after the DAQ finishes
                timeStamp = char(datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
                saveFileName = sprintf('Image_RX_Result_%s.mat', timeStamp);
                fullSavePath = fullfile(saveDirectory, saveFileName);
                
                % Save ONLY the raw hardware recording and parameters to disk right now
                save(fullSavePath, 'recordedData', 'imageParams');
                
                % Log the raw transmission success
                logFileName = fullfile(saveDirectory, 'transmission_session.txt');
                fid = fopen(logFileName, 'a');
                fprintf(fid, 'Transmitted and recorded raw data successfully at %s.\n', timeStamp);
                fclose(fid);

                app.ProgressTextArea.Value = 'Finished transmitting';
                drawnow;


                % =========================================================
                % RX LOGIC (Decode Signal)
                % =========================================================
                % Extract the raw recorded voltages from ai0
                %Finding which coulmn holds the data from ai0 channel
                ai0_index = contains(recordedData.Properties.VariableNames, 'ai0');
                rawVoltages = recordedData{:, ai0_index}; % ai0 channel
                
                % Send recorded voltages to your rx function
                decodedImageMatrix = image_tx_rx('rx', rawVoltages, imageParams);
                
                % =========================================================
                % DISPLAY RESULT IN GUI
                % =========================================================
                % Convert logical 0s and 1s to uint8 0s and 255s
                displayImgRx = uint8(decodedImageMatrix) * 255;
                
                % Replicate the 2D image into 3 channels (RGB) so the UI component accepts it
                displayImgRxRGB = cat(3, displayImgRx, displayImgRx, displayImgRx);
                
                % Send to the Right-side image box (Verify 'ImageRx' matches your UI exact spelling)
                app.ImageRX.ImageSource = displayImgRxRGB;
                
                % =========================================================
                % SAVE THE RECORDED DATA, PARAMETERS, AND IMAGE
                % =========================================================
                % Create a unique timestamp (e.g., '20261028_143000')
                timeStamp = char(datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
                
                % 1. Save the MATLAB data (.mat)
                saveFileName = sprintf('Image_RX_Result_%s.mat', timeStamp);
                fullSavePath = fullfile(saveDirectory, saveFileName);
                save(fullSavePath, 'recordedData', 'imageParams', 'decodedImageMatrix');
                
                % 2. Save the actual Image File (.png)
                % (imwrite handles logical matrices perfectly as clean black & white PNGs)
                imageFileName = sprintf('Image_RX_Result_%s.png', timeStamp);
                fullImagePath = fullfile(saveDirectory, imageFileName);
                imwrite(decodedImageMatrix, fullImagePath);
                
                % Log the success to your session text file
                logFileName = fullfile(saveDirectory, 'transmission_session.txt');
                fid = fopen(logFileName, 'a');
                fprintf(fid, 'Transmitted and recorded PHOTO successfully at %s.\n', timeStamp);
                fclose(fid);
                
                % Alert the user it finished
                uialert(app.UIFigure, 'Photo transmitted, decoded, and saved (.mat & .png) successfully!', 'Success');
                
            catch ME
                % If anything fails (hardware unplugged, sync failed, etc.)
                uialert(app.UIFigure, ['An error occurred: ', ME.message], 'Transmission Error');
            end
            
            % 4. Unlock Buttons regardless of success or failure
            app.TransmitphotoButton.Enable = 'on';
            app.LoadimagejpgButton.Enable = 'on';
        end

        % Button pushed function: loadRecordingFileButton
        function loadRecordingFileButtonPushed(app, event)
            % Open a dialog to select ONLY .mat files
            [fileName, filePath] = uigetfile({'*.mat', 'MATLAB Files (*.mat)'}, 'Select a Recorded MOCZ File');
            
            figure(app.UIFigure); % Force app back to front
            
            if isequal(fileName, 0)
                return; % User canceled
            end
            
            fullPath = fullfile(filePath, fileName);
            
            % Display the chosen path in the GUI 
            app.AnalyzePathEditField.Value = fullPath;

            % Load the recorded file
            loadedData = load(fullPath);
            % extract parameters (handles both standard signals and images)
            if isfield(loadedData, 'currentParams')
                params = loadedData.currentParams;
            elseif isfield(loadedData, 'imageParams')
                params = loadedData.imageParams;
            else
                error('Could not find currentParams or imageParams inside the file.');
            end

            app.KEditField.Value = params.K;
            app.LEditField.Value = params.L;
            app.TruncateEditField.Value =  app.KEditField.Value + 1 + app.LEditField.Value ; %default is not truncate polynomial coefficients.
            app.PowerEditField.Value = params.power;
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            fullFilePath = app.AnalyzePathEditField.Value;
            
            if isempty(fullFilePath) || ~isfile(fullFilePath)
                uialert(app.UIFigure, 'Please select a valid recorded .mat file first.', 'Error');
                return;
            end
            
            % Lock button to prevent double-clicking
            app.AnalyzeButton.Enable = 'off';
            drawnow;
            
            try
                % Load the recorded file
                loadedData = load(fullFilePath);
                
                % Safely extract parameters (handles both standard signals and images)
                if isfield(loadedData, 'currentParams')
                    params = loadedData.currentParams;
                elseif isfield(loadedData, 'imageParams')
                    params = loadedData.imageParams;
                else
                    error('Could not find currentParams or imageParams inside the file.');
                end
                
                % Safely extract the raw voltages (using the adaptive ai0 search trick)
                recData = loadedData.recordedData;
                if istimetable(recData) || istable(recData)
                    ai0_index = contains(recData.Properties.VariableNames, 'ai0');
                    rawVoltages = recData{:, ai0_index};
                else
                    rawVoltages = recData(:, 2); % Fallback
                end
                
                % Run the decoding and BER calculation
                [decoded_P, error_rate, ~, ~] = decode_mocz(rawVoltages, params, 1, app.TruncateEditField.Value);
                % thirs argument: 1 = DIZET mode
                
                % Update the GUI Number Edit Field with the BER
                % (Verify 'BEREditField' matches your component exactly)
                app.BEREditField.Value = error_rate;
                
                % Prepare the saved variables
                original_P = params.P;
                BER = error_rate;
                
                % Create the new filename with "_decoded" appended
                [filepath, name, ext] = fileparts(fullFilePath);
                saveFileName = sprintf('%s_decoded%s', name, ext);
                fullSavePath = fullfile(filepath, saveFileName);
                
                % Save the results
                save(fullSavePath, 'original_P', 'decoded_P', 'BER', 'params');
                
                uialert(app.UIFigure, sprintf('Analysis Complete!\nFile saved as: %s', saveFileName), 'success');
                
            catch ME
                uialert(app.UIFigure, ['Failed to analyze file: ', ME.message], 'Analysis Error');
            end
            
            % Unlock button
            app.AnalyzeButton.Enable = 'on';
        end

        % Callback function
        function STOPTXButtonPushed(app, event)
            app.stopRequested = true;
        end

        % Value changed function: UseDualDAQSetupSwitch
        function UseDualDAQSetupSwitchValueChanged(app, event)
            value = app.UseDualDAQSetupSwitch.Value;
            
        end

        % Button pushed function: GenerateSignalButton
        function GenerateSignalButtonPushed(app, event)
           % 1. Define the parameter pop-up
            prompt = {
                'Enter R (Radius):', ...
                'Enter K (Polynomial order):', ...
                'Enter L (Multipath channel protection taps):', ...
                'P Packet Type (0=Zeros, 1=Ones, 2=Random):'
            };
            dlgtitle = 'New MOCZ Signal Parameters';
            dims = [1 60];
            
            % Default values (R=1.1, K=5, L=25, P=0 for Random)
            definput = {'1.475', '5', '25', '2'}; 
            
            % Open the pop-up window
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            % Check if the user closed the window or clicked Cancel
            if isempty(answer)
                return; 
            end
            
            % Parse the inputs
            user_R = str2double(answer{1});
            user_K = str2double(answer{2});
            user_L = str2double(answer{3});
            user_P_type = str2double(answer{4});
            
            % 2. Open a UI to choose the save location
            % This pops up the file explorer so you can select the exact path/folder.
            [fileName, filePath] = uiputfile('*.mat', 'Choose where to save the generated signal');
            
            % Check if the user canceled the save dialog
            if isequal(fileName, 0) || isequal(filePath, 0)
                return; 
            end
            saveFullPath = fullfile(filePath, fileName);
            
            % 3. Generate the Signal Parameters
            simParams = makeParams();
            
            % Override with the new user-defined parameters
            simParams.R = user_R;
            simParams.K = user_K;
            simParams.L = user_L;
            simParams.packet_type = user_P_type; % Pass the numerical choice
            
            % Recalculate theta_c so the dimensions match the new K
            simParams.theta_c = ((2*pi) * ( (0:user_K-1) / user_K))';
            
            % Generate the signal 
            try
                [P, signal_pb_total, x_bb, group_delay, simParams] = generateFunc(simParams);
                
                % Save variables to the chosen path
                save(saveFullPath, 'P', 'signal_pb_total', 'x_bb', 'group_delay', 'simParams');
                
                % Update UI progress to show the full path
                app.ProgressTextArea.Value = ['Saved to: ', saveFullPath];

                % --- NEW CODE: Mirror the load file behavior ---
    
                % 1. Package the generated variables into a structure (mimicking what 'load' does)
                generatedData.P = P;
                generatedData.signal_pb_total = signal_pb_total;
                generatedData.x_bb = x_bb;
                generatedData.group_delay = group_delay;
                generatedData.simParams = simParams;
                
                % 2. Create a safe field name based on the file name
                safeFieldName = matlab.lang.makeValidName(erase(fileName, '.mat'));
                
                % 3. Create a brand-new, empty structure
                freshStruct = struct();
                
                % 4. Add your newly generated data to this fresh structure
                freshStruct.(safeFieldName) = generatedData;
                
                % 5. OVERWRITE the app property (wiping old data)
                app.TransmitSignalsData = freshStruct;
                
                % 6. OVERWRITE the text area so it only shows the new file name
                app.LoadedFilesTextArea.Value = {fileName};

                figure(app.UIFigure);
            catch ME
                errordlg(ME.message, 'Generation Error');
            end
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadSignalFile_button = uibutton(app.UIFigure, 'push');
            app.loadSignalFile_button.ButtonPushedFcn = createCallbackFcn(app, @loadSignalFile_buttonButtonPushed, true);
            app.loadSignalFile_button.Text = 'load file.mat';
            app.loadSignalFolder_button = uibutton(app.UIFigure, 'push');
            app.loadSignalFolder_button.ButtonPushedFcn = createCallbackFcn(app, @loadSignalFolder_buttonButtonPushed, true);
            app.loadSignalFolder_button.Text = 'load folder';
            app.START_button = uibutton(app.UIFigure, 'push');
            app.START_button.ButtonPushedFcn = createCallbackFcn(app, @START_buttonButtonPushed, true);
            app.START_button.Text = {'START'; 'TRANSMIT'};
            app.LoadedFilesTextArea = uitextarea(app.UIFigure);
            app.ProgressTextAreaLabel = uilabel(app.UIFigure);
            app.ProgressTextAreaLabel.Text = 'Progress';
            app.ProgressTextArea = uitextarea(app.UIFigure);
            app.SignalfilesqueueLabel = uilabel(app.UIFigure);
            app.SignalfilesqueueLabel.Text = 'Signal files queue';
            app.ChooserecordingspathButton = uibutton(app.UIFigure, 'push');
            app.ChooserecordingspathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooserecordingspathButtonPushed, true);
            app.ChooserecordingspathButton.Text = {'Choose recordings'; ' path'};
            app.recordingsPathEditField = uieditfield(app.UIFigure, 'text');
            app.recordingsPathEditField.Editable = 'off';
            app.PausetimesEditFieldLabel = uilabel(app.UIFigure);
            app.PausetimesEditFieldLabel.Text = 'Pause time [s]';
            app.PausetimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PausetimeEditField.Value = 2;
            app.AnalyzeRecordingPanel = uipanel(app.UIFigure);
            app.AnalyzeRecordingPanel.Title = 'Analyze Recording ';
            app.AnalyzeButton = uibutton(app.AnalyzeRecordingPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.loadRecordingFileButton = uibutton(app.AnalyzeRecordingPanel, 'push');
            app.loadRecordingFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadRecordingFileButtonPushed, true);
            app.loadRecordingFileButton.Text = 'load recording.mat';
            app.AnalyzePathEditField = uieditfield(app.AnalyzeRecordingPanel, 'text');
            app.KEditFieldLabel = uilabel(app.AnalyzeRecordingPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.AnalyzeRecordingPanel, 'numeric');
            app.LEditFieldLabel = uilabel(app.AnalyzeRecordingPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.AnalyzeRecordingPanel, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.AnalyzeRecordingPanel);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.AnalyzeRecordingPanel, 'numeric');
            app.BEREditFieldLabel = uilabel(app.AnalyzeRecordingPanel);
            app.BEREditFieldLabel.Text = 'BER';
            app.BEREditField = uieditfield(app.AnalyzeRecordingPanel, 'numeric');
            app.TruncatetoEditFieldLabel = uilabel(app.AnalyzeRecordingPanel);
            app.TruncatetoEditFieldLabel.Text = 'Truncate to:';
            app.TruncateEditField = uieditfield(app.AnalyzeRecordingPanel, 'numeric');
            app.TransmitChirpfortestCheckBox = uicheckbox(app.UIFigure);
            app.TransmitChirpfortestCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransmitChirpfortestCheckBoxValueChanged, true);
            app.TransmitChirpfortestCheckBox.Text = 'Transmit Chirp for test';
            app.MOCZexperimentGUIMOCZsignalsmodeLabel = uilabel(app.UIFigure);
            app.MOCZexperimentGUIMOCZsignalsmodeLabel.Text = 'MOCZ experiment GUI - MOCZ signals mode';
            app.ChirpPathEditField = uieditfield(app.UIFigure, 'text');
            app.ExtrarecordingtimesEditFieldLabel = uilabel(app.UIFigure);
            app.ExtrarecordingtimesEditFieldLabel.Text = 'Extra recording time [s]';
            app.ExtraRecordingTime = uieditfield(app.UIFigure, 'numeric');
            app.ExtraRecordingTime.Value = 0.2;
            app.ImagetransmissionPanel = uipanel(app.UIFigure);
            app.ImagetransmissionPanel.Title = 'Image transmission, K = 8 - DiZeT decoding';
            app.ImageTX = uiimage(app.ImagetransmissionPanel);
            app.ImageRX = uiimage(app.ImagetransmissionPanel);
            app.TransmitphotoButton = uibutton(app.ImagetransmissionPanel, 'push');
            app.TransmitphotoButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitphotoButtonPushed, true);
            app.TransmitphotoButton.Text = 'Transmit photo';
            app.LoadimagejpgButton = uibutton(app.ImagetransmissionPanel, 'push');
            app.LoadimagejpgButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagejpgButtonPushed, true);
            app.LoadimagejpgButton.Text = 'Load image .jpg';
            app.ImagePathEditField = uieditfield(app.ImagetransmissionPanel, 'text');
            app.ImageTxLabel = uilabel(app.ImagetransmissionPanel);
            app.ImageTxLabel.Text = 'Image Tx';
            app.ImageRxLabel = uilabel(app.ImagetransmissionPanel);
            app.ImageRxLabel.Text = 'Image Rx';
            app.ClearqueueButton = uibutton(app.UIFigure, 'push');
            app.ClearqueueButton.ButtonPushedFcn = createCallbackFcn(app, @ClearqueueButtonPushed, true);
            app.ClearqueueButton.Text = 'Clear queue';
            app.DAQSETUPSwitchLabel = uilabel(app.UIFigure);
            app.DAQSETUPSwitchLabel.Text = '2 DAQ SETUP';
            app.UseDualDAQSetupSwitch = uiswitch(app.UIFigure, 'slider');
            app.UseDualDAQSetupSwitch.Items = {'Single', 'Dual'};
            app.UseDualDAQSetupSwitch.ValueChangedFcn = createCallbackFcn(app, @UseDualDAQSetupSwitchValueChanged, true);
            app.UseDualDAQSetupSwitch.Value = 'Single';
            app.TX_ID_DevEditFieldLabel = uilabel(app.UIFigure);
            app.TX_ID_DevEditFieldLabel.Text = 'TX_ID_Dev';
            app.TX_ID_DevEditField = uieditfield(app.UIFigure, 'numeric');
            app.TX_ID_DevEditField.Value = 2;
            app.RX_ID_DevEditFieldLabel = uilabel(app.UIFigure);
            app.RX_ID_DevEditFieldLabel.Text = 'RX_ID_Dev';
            app.RX_ID_DevEditField = uieditfield(app.UIFigure, 'numeric');
            app.RX_ID_DevEditField.Value = 1;
            app.GenerateSignalButton = uibutton(app.UIFigure, 'push');
            app.GenerateSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalButtonPushed, true);
            app.GenerateSignalButton.Text = 'Generate Signal';
            app.LocationLabel = uilabel(app.UIFigure);
            app.LocationLabel.Text = 'Location ';
            app.LocationDropDown = uidropdown(app.UIFigure);
            app.LocationDropDown.Items = {'Middle of pool', 'Near wall', 'Near wall and surface'};
            app.LocationDropDown.Value = 'Middle of pool';
            app.DistanceTxRxmEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceTxRxmEditFieldLabel.Text = 'Distance Tx-Rx [m]';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pool_transmit_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end