classdef recieveGui_31_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExaminedPacketEditField       matlab.ui.control.NumericEditField
        ExaminedPacketEditFieldLabel  matlab.ui.control.Label
        RXchannelEditField            matlab.ui.control.NumericEditField
        RXchannelEditFieldLabel       matlab.ui.control.Label
        MessageDataPanel              matlab.ui.container.Panel
        Label_Bname                   matlab.ui.control.Label
        Label_Bval                    matlab.ui.control.Label
        Label_portval                 matlab.ui.control.Label
        Label_portname                matlab.ui.control.Label
        Label_errRatename             matlab.ui.control.Label
        Label_errRateval              matlab.ui.control.Label
        Label_Chirpname               matlab.ui.control.Label
        Label_Chirpval                matlab.ui.control.Label
        Label_SNRname                 matlab.ui.control.Label
        Label_SNRval                  matlab.ui.control.Label
        Label_Kname                   matlab.ui.control.Label
        Label_Kval                    matlab.ui.control.Label
        Label_Lname                   matlab.ui.control.Label
        Label_Lval                    matlab.ui.control.Label
        RECIEVINGGUILabel             matlab.ui.control.Label
        SymbolsandImageVisualizationPanel  matlab.ui.container.Panel
        showOrgCheckBox               matlab.ui.control.CheckBox
        spectAxes                     matlab.ui.control.UIAxes
        circleAxes                    matlab.ui.control.UIAxes
        SignalDataVisualizationPanel  matlab.ui.container.Panel
        freqAxes                      matlab.ui.control.UIAxes
        signalAxes                    matlab.ui.control.UIAxes
        Label_2                       matlab.ui.control.Label
        nameOfFile                    matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        matlab2023bLabel              matlab.ui.control.Label
        loadrecievedfilematButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        recievedSignalFileName = [];
        timeAndDataMatrix = [];
        
        L = [];
        K = [];
        isChirp = [];
        snr = [];
        P = [];
        B = [];
        simParams = [];
        myPlotHandle = [];
        Loaded = [];
    end
    
    methods (Access = private)
        
        function struct2vars(app)
            S = app.simParams;
            fields = fieldnames(S);
            for i = 1:numel(fields)
                fieldName = fields{i};
                fieldValue = S.(fieldName);
                assignin('caller', fieldName, fieldValue);
            end
        end

        function toggleLine(app, src, h1)
            if src.Value
                h1.Visible = 'on';
            end
        end
        
        function extractFromStruct(app, recievedSignalStruct)
            %notating:
            ParamsStrct = recievedSignalStruct.currentParams;
            signalData = recievedSignalStruct.recordedData;

            %updating values of params (error ratio will be after.):
            app.Label_Lval.Text = string(ParamsStrct.L);
            app.L = ParamsStrct.L;

            app.Label_Kval.Text = string(ParamsStrct.K);
            app.K = ParamsStrct.K;

            app.Label_Bval.Text = string(ParamsStrct.B);
            app.B = ParamsStrct.B;

            app.simParams = ParamsStrct;

            colName = signalData.Properties.VariableNames{1};
            app.Label_portval.Text = colName;
            % TODO: handle these 3:
            % 1. app.chirpTextArea.Value = ParamsStrct.isChirp; not exist?
            % app.isChirp = ParamsStrct.isChirp;
            % 2. app.SNRTextArea.Value = ParamsStrct.snr;
            % app.snr = ParamsStrct.snr;
            % 3. app.P = ParamsStrct.P;
            
            % updating the data (1st coll in matrix is time and every col
            % after is data in channel: Dev5_ai0, Dev5_ai1).
            % this is why we must track what channel we used! right now we
            % only treat the second col as the good one.
            % TODO: a jumping prompt that stated that we assume our data is "signalData.VariableNames(1)" port!
            app.timeAndDataMatrix = [];
            app.timeAndDataMatrix(:, 1) = seconds(signalData.Time);
            app.timeAndDataMatrix = [app.timeAndDataMatrix, signalData.Variables];

            % TODO: dizet decoder (+ cross correlator?) that will calculate: 
            % [~, ratio] = biterr(P, DiZeT(simParams, strct.signal)); app.errrateTextArea.Value = ratio;
        end

        function analyzeSignal(app)
            %% 1. notating - assumed data is 2nd col only!! in timetable
            time = app.timeAndDataMatrix(:, 1);
            channel = app.RXchannelEditField.Value + 1;
            % dataRecieved = app.timeAndDataMatrix(:, 2);
            dataRecieved = app.timeAndDataMatrix(:, channel);
            struct2vars(app);
            t = (0:length(dataRecieved)-1)' / Fs;
            %% 2. decoding process
            [~, error_rate, x_decoded_matrix] = decode_mocz(dataRecieved, app.simParams);
            %% 3. update biterr text box and ploting in axes objects (take random b from B)
            % update biterr & message num texts
            app.Label_errRateval.Text = string(error_rate);

            % 1. circle axes (random packet)
            idx = randi(B);
            idx = app.ExaminedPacketEditField.Value;
            x_vec_decoded = x_decoded_matrix(:, idx);
            decoded_alphas = roots(flip(x_vec_decoded.'));
            M = P(:, idx);
            alphas = ((1-M)*(R^(-1)) + M * R) .* exp(1i*theta_c);
            
            cla(app.circleAxes);
            hold(app.circleAxes, 'on');
            h1 = scatter(app.circleAxes, real(decoded_alphas), imag(decoded_alphas), 50, 'filled', 'b');
            grid(app.circleAxes, 'on'); 
            axis(app.circleAxes, 'equal');
            app.myPlotHandle = scatter(app.circleAxes, real(alphas), imag(alphas), 50, 'filled', 'g');
            
            thetas_for_plot = linspace(0, 2*pi, 300);
            plot(app.circleAxes, R * exp(1i*thetas_for_plot), 'r', 'LineWidth', 1);
            plot(app.circleAxes, (1/R) * exp(1i*thetas_for_plot), 'r', 'LineWidth', 1);
            plot(app.circleAxes, exp(1i*thetas_for_plot), 'k--', 'LineWidth', 0.5);
            
            legend(app.circleAxes, [h1, app.myPlotHandle], {'Signal Symbols', 'Original Symbols'});
            title(app.circleAxes, 'Enc. and Rec. Symbols of Random Packet'); %Huffman BMOCZ
            xlabel(app.circleAxes, 'Re(z)');
            ylabel(app.circleAxes, 'Im(z)');
            hold(app.circleAxes, 'off');
            % app.Label_messagenumberVal.Text = string(idx) + " / " + string(B); % 1/20, 7/20, .. in label object

            % 2. spect axes
            [~, F, T, P] = spectrogram(dataRecieved, 4096, 2048, 4096, Fs, 'yaxis');
            imagesc(app.spectAxes, T, F / 1000, 10*log10(P));
            set(app.spectAxes, 'YDir', 'normal');
            colorbar(app.spectAxes);

            % 3. signal axes
            plot(app.signalAxes, t, dataRecieved);
            grid(app.signalAxes, 'on');

            % freq axes
            N = size(t, 1);
            FT = fftshift(fft(dataRecieved));
            f = ((-N/2 : N/2-1) * (Fs/N)) / 1000;
            plot(app.freqAxes, f, abs(FT), 'LineWidth', 1.5);
            xlim(app.freqAxes, [-50, 50]);
            xline(app.freqAxes, -15, '--');
            xline(app.freqAxes, 15, '--');
            grid(app.freqAxes, 'on');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % linkaxes([app.spectAxes, app.signalAxes], 'x')
        end

        % Button pushed function: loadrecievedfilematButton
        function loadrecievedfilematButtonPushed(app, event)
            %Open a dialog to select only .mat files
            [fileName, filePath] = uigetfile('*.mat', 'Select a .MAT-file');
            figure(app.UIFigure);
            %Check if the user clicked "Cancel"
            if isequal(fileName, 0)
                return; % Exit the function without doing anything
            end

            % Display the file name in the text box next to the button
            % (Replace 'signal_file_name' with the actual name of your text component in the App Designer hierarchy)
            app.nameOfFile.Value = fileName;

            %update property:
            app.recievedSignalFileName = fileName;

            %Load the file and extract the data
            fullFilePath = fullfile(filePath, fileName);

            try
                % Load ONLY the 'signal' variable from the .mat file into a structure
                loadedData = load(fullFilePath);
                app.Loaded = loadedData;

                % fprintf('loaded succesfuly! now try to display...\n');
            catch 
                % Pop up an error if the .mat file doesn't actually contain a variable named 'signal'
                uialert(app.UIFigure, 'The selected file does not contain a recievedSignal struct.', 'Load Error');
            end

            % try
                % update params text boxes in gui, and update properties
                extractFromStruct(app, loadedData);

                % analyze the signal we got:
                %%%%%%% method analyzeSignal
                analyzeSignal(app);

            % catch
                % TODO: complete
            % end

        end

        % Value changed function: showOrgCheckBox
        function showOrgCheckBoxValueChanged(app, event)
            isChecked = app.showOrgCheckBox.Value;
            if ~isempty(app.myPlotHandle) && isvalid(app.myPlotHandle)
                if isChecked
                    app.myPlotHandle.Visible = 'on';
                else
                    app.myPlotHandle.Visible = 'off';
                end
            end
        end

        % Value changed function: RXchannelEditField
        function RXchannelEditFieldValueChanged(app, event)
            % plot everything again
            loadedData = app.Loaded;
            extractFromStruct(app, loadedData);
            analyzeSignal(app);
        end

        % Value changed function: ExaminedPacketEditField
        function ExaminedPacketEditFieldValueChanged(app, event)
            loadedData = app.Loaded;
            extractFromStruct(app, loadedData);
            analyzeSignal(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadrecievedfilematButton = uibutton(app.UIFigure, 'push');
            app.loadrecievedfilematButton.ButtonPushedFcn = createCallbackFcn(app, @loadrecievedfilematButtonPushed, true);
            app.loadrecievedfilematButton.Text = 'load recieved file.mat';
            app.matlab2023bLabel = uilabel(app.UIFigure);
            app.matlab2023bLabel.Text = 'matlab 2023b';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.nameOfFile = uitextarea(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'mat file with struct recievedSignal';
            app.SignalDataVisualizationPanel = uipanel(app.UIFigure);
            app.SignalDataVisualizationPanel.Title = 'Signal Data Visualization';
            app.signalAxes = uiaxes(app.SignalDataVisualizationPanel);
            title(app.signalAxes, 'Message PB Signal')
            xlabel(app.signalAxes, 'Time [sec]')
            ylabel(app.signalAxes, 'Mgnitude')
            zlabel(app.signalAxes, 'Z')
            app.freqAxes = uiaxes(app.SignalDataVisualizationPanel);
            title(app.freqAxes, 'PB Signal Fourier Trans.')
            xlabel(app.freqAxes, 'Frequency (kHz)')
            ylabel(app.freqAxes, 'Magnitude')
            zlabel(app.freqAxes, 'Z')
            app.SymbolsandImageVisualizationPanel = uipanel(app.UIFigure);
            app.SymbolsandImageVisualizationPanel.Title = 'Symbols and Image Visualization';
            app.circleAxes = uiaxes(app.SymbolsandImageVisualizationPanel);
            title(app.circleAxes, 'Examined Packet Symbols')
            xlabel(app.circleAxes, 'X')
            ylabel(app.circleAxes, 'Y')
            zlabel(app.circleAxes, 'Z')
            app.spectAxes = uiaxes(app.SymbolsandImageVisualizationPanel);
            title(app.spectAxes, 'PB Signal Spectrogram')
            xlabel(app.spectAxes, 'Time (sec)')
            ylabel(app.spectAxes, 'Frequency (kHz)')
            zlabel(app.spectAxes, 'Z')
            app.showOrgCheckBox = uicheckbox(app.SymbolsandImageVisualizationPanel);
            app.showOrgCheckBox.ValueChangedFcn = createCallbackFcn(app, @showOrgCheckBoxValueChanged, true);
            app.showOrgCheckBox.Text = 'Show Org Sym';
            app.showOrgCheckBox.Value = true;
            app.RECIEVINGGUILabel = uilabel(app.UIFigure);
            app.RECIEVINGGUILabel.Text = 'RECIEVING GUI';
            app.MessageDataPanel = uipanel(app.UIFigure);
            app.MessageDataPanel.Title = 'Message Data';
            app.Label_Lval = uilabel(app.MessageDataPanel);
            app.Label_Lval.Text = '_';
            app.Label_Lname = uilabel(app.MessageDataPanel);
            app.Label_Lname.Text = 'L:';
            app.Label_Kval = uilabel(app.MessageDataPanel);
            app.Label_Kval.Text = '_';
            app.Label_Kname = uilabel(app.MessageDataPanel);
            app.Label_Kname.Text = 'K:';
            app.Label_SNRval = uilabel(app.MessageDataPanel);
            app.Label_SNRval.Text = '_';
            app.Label_SNRname = uilabel(app.MessageDataPanel);
            app.Label_SNRname.Text = 'SNR:';
            app.Label_Chirpval = uilabel(app.MessageDataPanel);
            app.Label_Chirpval.Text = '_';
            app.Label_Chirpname = uilabel(app.MessageDataPanel);
            app.Label_Chirpname.Text = 'chirp?';
            app.Label_errRateval = uilabel(app.MessageDataPanel);
            app.Label_errRateval.Text = '_';
            app.Label_errRatename = uilabel(app.MessageDataPanel);
            app.Label_errRatename.Text = 'errRate:';
            app.Label_portname = uilabel(app.MessageDataPanel);
            app.Label_portname.Text = 'port name:';
            app.Label_portval = uilabel(app.MessageDataPanel);
            app.Label_portval.Text = '_';
            app.Label_Bval = uilabel(app.MessageDataPanel);
            app.Label_Bval.Text = '_';
            app.Label_Bname = uilabel(app.MessageDataPanel);
            app.Label_Bname.Text = 'B:';
            app.RXchannelEditFieldLabel = uilabel(app.UIFigure);
            app.RXchannelEditFieldLabel.Text = 'RX channel';
            app.RXchannelEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXchannelEditField.ValueChangedFcn = createCallbackFcn(app, @RXchannelEditFieldValueChanged, true);
            app.RXchannelEditField.Value = 1;
            app.ExaminedPacketEditFieldLabel = uilabel(app.UIFigure);
            app.ExaminedPacketEditFieldLabel.Text = 'Examined Packet';
            app.ExaminedPacketEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExaminedPacketEditField.Limits = [1 20];
            app.ExaminedPacketEditField.ValueChangedFcn = createCallbackFcn(app, @ExaminedPacketEditFieldValueChanged, true);
            app.ExaminedPacketEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recieveGui_31_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end